classdef SNR_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MostrarSNRButton        matlab.ui.control.Button
        SNRAnalyzerLabel        matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        MostrarWaveformButton   matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        arquivo % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.arquivo = app.FileNameEditField.Value;
            
        end

        % Button pushed function: MostrarWaveformButton
        function MostrarWaveformButtonPushed(app, event)
%arquivo = 'sinal_AM_recebido_Lima_1130KHz.bin';  % use neste comando o nome do arquivo com suas amostras
id = fopen(app.arquivo, 'rb');
f=1130000;
amostras = fread(id,[2, inf],'float');  % amostras extraídas

N = length(amostras);                                                %  número de amostras a extrair do arquivo

sgn=Sinal;
sgn.tempo=1:N;
sgn.compfase=zeros(1,N);
sgn.compquad=zeros(1,N);
sgn.amp=zeros(1,N); 
%Alocação dos valores nas componentes
for i=1:N
    sgn.compfase(1,i)=amostras(1,i);
end
for i=1:N
    sgn.compquad(1,i)=amostras(1,i);
end
%Cálculos para demodulação
for i=1:N
    sgn.amp(1,i)=((sgn.compfase(1,i)*cos(2*pi*f*i))-(sgn.compquad(1,i)*sin(2*pi*f*i)))/cos(2*pi*f*i);
end
plot(app.UIAxes,sgn.tempo,sgn.amp);

        end

        % Button pushed function: MostrarSNRButton
        function MostrarSNRButtonPushed(app, event)
%arquivo = 'sinal_AM_recebido_Lima_1130KHz.bin';  % use neste comando o nome do arquivo com suas amostras
id = fopen(app.arquivo, 'rb');
f=1130000;
pot_ruido=3.9606e-08;
amostras = fread(id,[2, inf],'float');  % amostras extraídas

N = length(amostras);                                                %  número de amostras a extrair do arquivo

sgn=Sinal;
sgn.tempo=1:N;
sgn.compfase=zeros(1,N);
sgn.compquad=zeros(1,N);
sgn.amp=zeros(1,N); 
%Alocação dos valores nas componentes
for i=1:N
    sgn.compfase(1,i)=amostras(1,i);
end
for i=1:N
    sgn.compquad(1,i)=amostras(1,i);
end
%Cálculos para demodulação
for i=1:N
    sgn.amp(1,i)=((sgn.compfase(1,i)*cos(2*pi*f*i))-(sgn.compquad(1,i)*sin(2*pi*f*i)))/cos(2*pi*f*i);
end
%Cálculo da RSR após demodulação
x=0;
for i=1:N 
    x = x + sgn.amp(1,i)^2;
end
pot=x/N;
RSR=pot/pot_ruido;
app.UITable.Data=RSR;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MostrarSNRButton = uibutton(app.UIFigure, 'push');
            app.MostrarSNRButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarSNRButtonPushed, true);
            app.MostrarSNRButton.Text = 'Mostrar SNR';
            app.SNRAnalyzerLabel = uilabel(app.UIFigure);
            app.SNRAnalyzerLabel.Text = 'SNR Analyzer';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'SNR after demodulation'};
            app.UITable.RowName = {};
            app.MostrarWaveformButton = uibutton(app.UIFigure, 'push');
            app.MostrarWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarWaveformButtonPushed, true);
            app.MostrarWaveformButton.Text = 'Mostrar Waveform ';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'Tempo')
            ylabel(app.UIAxes, 'Amplitude')
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNR_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end